classdef GUI_LocoD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LocoDUIFigure        matlab.ui.Figure
        RecordOnlineButton   matlab.ui.control.Button
        Image                matlab.ui.control.Image
        RecordOfflineButton  matlab.ui.control.Button
    end

    properties (Access = private)
        AFE_app_Off GUI_AFE_Off;     % Analog front-end
        recprops_Off RecordingPropertiesOff;
        AFE_app_On GUI_AFE_On;     % Analog front-end
        recprops_On RecordingPropertiesOn;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Delete all other apps
            allfigs = findall(0, 'Type', 'figure');
            allfigs = allfigs(allfigs ~= app.LocoDUIFigure);
            if ~isempty(allfigs)
                answer = questdlg('LocoD will close everything else. Continue?', 'answer', 'yes', 'no', 'yes');
                if strcmp(answer, 'no')
                    % Don't continue
                    delete(app)
                    return
                end
            end
            delete(allfigs);

            % Delete all dangling timers
            delete(timerfindall);
        end

        % Button pushed function: RecordOfflineButton
        function RecordOfflineButtonPushed(app, event)
            % Open the AFE window, and let them know that I am the parent.
            app.AFE_app_Off = GUI_AFE_Off(app);

        end

        % Button pushed function: RecordOnlineButton
        function RecordOnlineButtonPushed(app, event)
            % Open the AFE window, and let them know that I am the parent.
            app.AFE_app_On = GUI_AFE_On(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LocoDUIFigure = uifigure('Visible', 'off');
            app.LocoDUIFigure.Name = 'LocoD';
            app.LocoDUIFigure.Icon = 'LocoD2.png';
            app.RecordOfflineButton = uibutton(app.LocoDUIFigure, 'push');
            app.RecordOfflineButton.ButtonPushedFcn = createCallbackFcn(app, @RecordOfflineButtonPushed, true);
            app.RecordOfflineButton.Text = 'Record Offline';
            app.Image = uiimage(app.LocoDUIFigure);
            app.Image.ImageSource = 'LocoD2.png';
            app.RecordOnlineButton = uibutton(app.LocoDUIFigure, 'push');
            app.RecordOnlineButton.ButtonPushedFcn = createCallbackFcn(app, @RecordOnlineButtonPushed, true);
            app.RecordOnlineButton.Text = 'Record Online';
            % Show the figure after all components are created
            app.LocoDUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_LocoD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LocoDUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LocoDUIFigure)
        end
    end
end